classdef docScannerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DocumentScanner           matlab.ui.Figure
        SeleccionararchivoButton  matlab.ui.control.Button
        imagenOriginal            matlab.ui.control.Image
        imagenFInal               matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         addpath('src/');

        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            fullPath = strcat(path,file);
            image = readImage(fullPath);

            % Mostramos la original
            app.imagenOriginal.ImageSource = image;

            originalImage = image;
            % Aplicamos el preprocesado (y convertimos a binaria)
            image = preprocessing(image);

            % Detectamos las esquinas
            corners = detectCorners(image, 'manual');

            % Corregimos perspectiva
            image = fixPerspective(originalImage, corners);

            app.imagenFInal.ImageSource = image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DocumentScanner = uifigure('Visible', 'off');
            app.DocumentScanner.Name = 'Document Scanner';
            app.SeleccionararchivoButton = uibutton(app.DocumentScanner, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo ...';
            app.imagenOriginal = uiimage(app.DocumentScanner);
            app.imagenFInal = uiimage(app.DocumentScanner);
            % Show the figure after all components are created
            app.DocumentScanner.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = docScannerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DocumentScanner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DocumentScanner)
        end
    end
end